classdef interpreter_comm_engagement_dual_pc_autoreflow_backup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TissueDropDown                 matlab.ui.control.DropDown
        TissueDropDownLabel            matlab.ui.control.Label
        BiomarkerDropDown              matlab.ui.control.DropDown
        PCModeDropDown                 matlab.ui.control.DropDown
        BiomarkerDropDown_2Label       matlab.ui.control.Label
        PCModeDropDownLabel            matlab.ui.control.Label
        InterpretationVoteButtonGroup  matlab.ui.container.ButtonGroup
        DislikeButton                  matlab.ui.control.RadioButton
        LikeButton                     matlab.ui.control.RadioButton
        SaveButton                     matlab.ui.control.StateButton
        RightPanel                     matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        InterpretationsTab             matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        CommunityDownwardInterpretationTextAreaLabel  matlab.ui.control.Label
        CommunityDownwardInterpretationTextArea  matlab.ui.control.TextArea
        CommunityUpwardInterpretationTextAreaLabel  matlab.ui.control.Label
        CommunityUpwardInterpretationTextArea  matlab.ui.control.TextArea
        WhatisyourinterpretationTextArea_2  matlab.ui.control.TextArea
        WhatisyourinterpretationTextAreaLabel_2  matlab.ui.control.Label
        WhatisyourinterpretationTextAreaLabel  matlab.ui.control.Label
        WhatisyourinterpretationTextArea  matlab.ui.control.TextArea
        Panel                          matlab.ui.container.Panel
        ImageAxes                      matlab.ui.control.UIAxes
        CheckListTab                   matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        SetupExportTab                 matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        YoureDoneLabel                 matlab.ui.control.Label
        SeeYouSoonLabel                matlab.ui.control.Label
        ExportDataButton               matlab.ui.control.Button
        OutputDirectoryButton          matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        ChoosedirectorytosavedataandprogressLabel  matlab.ui.control.Label
        OncefinishedexportdatatooutputdirectoryLabel  matlab.ui.control.Label
        LoadDataButton                 matlab.ui.control.Button
        LoadinpartiallycompletedxlsxfiletocontinueLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        % Declare properties of the interpreter_comm_engagement_option2
        % class
        Data
        displayedIndices
        dir_name
        user_table
        globalFig
        hlink
        hlink2
        hlink3
    end 
    
    methods (Access = private)
        
        function updateFigs(app, ~)  

            if isequal(app.TissueDropDown.Value, 'SELECT') || isequal(app.BiomarkerDropDown.Value, 'SELECT') || isequal(app.PCModeDropDown.Value, 'SELECT')
                if isequal(app.TissueDropDown.Value, 'Lateral Meniscus') || isequal(app.TissueDropDown.Value, 'Medial Meniscus')
                    app.BiomarkerDropDown.Items = {'Bone Shape'};
                else 
                    app.BiomarkerDropDown.Items = {'SELECT','Bone Shape', 'Cartilage Thickness', 'Cartilage T2'};
                end                 
                return;
            else
                
                if isequal(app.TissueDropDown.Value, 'Lateral Meniscus') || isequal(app.TissueDropDown.Value, 'Medial Meniscus')
                    app.BiomarkerDropDown.Items = {'Bone Shape'};
                else 
                    app.BiomarkerDropDown.Items = {'SELECT','Bone Shape', 'Cartilage Thickness', 'Cartilage T2'};
                end 
                              
                files = matlab.apputil.getInstalledAppInfo;
                
                if isempty(files)
                    curr_path = pwd;
                    fig_dir = fullfile(curr_path,'fig_files_sans_bars/');
%                   png_dir = fullfile(curr_path, 'interpreter_comm_engagement_dual_pc_autoreflow','png_files/');
                    map = fullfile(curr_path,'redblueColorMap.mat');
                else 
                    [curr_path,~,~] = fileparts(files(1).location);
%                     fig_dir = fullfile(curr_path, 'interpreter_comm_engagement_dual_pc_autoreflow','fig_files_sans_bars/');
                    fig_dir = fullfile(curr_path, 'pc_interpreter/Documents','fig_files_sans_bars/');

%                   png_dir = fullfile(curr_path, 'interpreter_comm_engagement_dual_pc_autoreflow','png_files/');
%                     map = fullfile(curr_path, 'interpreter_comm_engagement_dual_pc_autoreflow','redblueColorMap.mat');
                    map = fullfile(curr_path, 'pc_interpreter/Documents','redblueColorMap.mat');

                end

                load(map, 'c');
                    
                %**********************************************************************
                % tissues: 'Femur', 'Patella', 'Tibia', 'Lateral Meniscus', 'Medial Meniscus'
                temp_tissue = app.TissueDropDown.Value;
    
                if isequal(temp_tissue, 'Femur') || isequal(temp_tissue, 'Patella') || isequal(temp_tissue, 'Tibia')
                    tissue = temp_tissue;
                else
                    tissue = regexprep(temp_tissue, ' ', '_');
                end
                    
                % biomarkers: 'Bone Shape', 'Cartilage Thickness', 'Cartilage T2'
                temp_bio = app.BiomarkerDropDown.Value;
    
                if isequal(temp_bio, 'Bone Shape')
                    bio = regexprep(temp_bio, ' ', '_');
                else 
                    bio = regexprep(temp_bio, 'Cartilage', '');
                    bio = regexprep(bio, ' ', '');
                end
                
                % PCs:
                pc = app.PCModeDropDown.Value;
                pc = regexprep(pc, ' ', '');
                
                % put strings together
                full = [tissue,'_',bio,'_',pc,'.fig'];
                img1 = fullfile(fig_dir, full);
                %**********************************************************************
    
                f_new = openfig(img1, 'invisible'); 
                colormap(f_new,c);
                ax = findobj(f_new, 'Type','Axes');               
                
                copyobj(ax, app.Panel);

                colormap(app.UIFigure,c);
                
                delete(f_new)

                %add image scale to app
                thick_bar = 'Thickness_bar_north_white.png';
                t2_bar = 'T2_bar_north_white.png';
                
                if isequal(app.BiomarkerDropDown.Value, 'Cartilage Thickness')
        
                    bar1 = fullfile(fig_dir, thick_bar);
                    im = imread(bar1);
                    imagesc(app.ImageAxes, im);
                    app.ImageAxes.Visible = 'off';
                    
                elseif isequal(app.BiomarkerDropDown.Value, 'Cartilage T2')
                    
                    bar1 = fullfile(fig_dir, t2_bar);
                    im = imread(bar1);
                    imagesc(app.ImageAxes, im);
                    app.ImageAxes.Visible = 'off';
                else 
                    app.ImageAxes.Visible = 'off';
                    delete(app.ImageAxes.Children)
                end
                               
            end
            
            %connect three subplot axes so they rotate in unison
            children = app.Panel.Children;
            app.hlink = linkprop([children(1),children(2),children(3)],{'CameraPosition','CameraUpVector'}); 
%             rotate3d on;

        end
        
        function deleteFigs(app)
            delete(app.Panel.Children)
        end
        
        
        function InitializeTable(app)
            
            if isempty(app.user_table)
                
                % Original table - single interpretation space
%                 T = readtable('C:/Users/gabbie/Documents/Git_repos/oai_interpreter/GUI_dev_files/User_input.xlsx');

                % Secondary table - dual interpretation spaces
                files = matlab.apputil.getInstalledAppInfo;
                
                if isempty(files)
                    curr_path = pwd;
%                     my_table = fullfile(curr_path,'User_input_dual_interpretation.xlsx'); %excel file
                    my_table = fullfile(curr_path,'User_input_dual_interpretation.csv'); % csv file


                else 
                    [curr_path,~,~] = fileparts(files(1).location);
%                     my_table = fullfile(curr_path,
%                     'interpreter_comm_engagement_dual_pc_autoreflow','User_input_dual_interpretation.xlsx'); %excel 
%                     my_table = fullfile(curr_path, 'interpreter_comm_engagement_dual_pc_autoreflow','User_input_dual_interpretation.csv'); %csv
                    my_table = fullfile(curr_path, 'pc_interpreter/Documents','User_input_dual_interpretation.csv'); %csv


                end
                T = readtable(my_table);
                
            else 
                % Load the data
                T = app.user_table;
            end
            
            %extra step needed if loading in table from .csv rather than
            %excel sheet - formats logical columns allows for checkboxes
            T.Complete = logical(T.Complete);
            T.VoteOfAgreement = logical(T.VoteOfAgreement);
            
            % Configure table
            app.UITable.Data = T;
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = [false false false false false false false false false];
            app.UITable.RowName = 'numbered';
%             app.UITable.ColumnFormat = {'logical','char', 'char', 'numeric','logical','char', 'char','char', 'char'};
            
            % update color of case 'Complete' column cell
            [row,col] = find(app.UITable.Data.Complete > 0);
            s = uistyle('BackgroundColor', [0,0.7,0.2]);
            addStyle(app.UITable, s, 'cell', [row,col]);
            
            % updating color of user vote to delineate 'like' or 'dislike'
            [row2, col2] = find((app.UITable.Data.Complete > 0) & (app.UITable.Data.VoteOfAgreement > 0));
            pause(0.001);
            if isempty(row2)
                [row3, col3] = find((app.UITable.Data.Complete > 0) & (app.UITable.Data.VoteOfAgreement == 0));
                s3 = uistyle('BackgroundColor', [1, 0.6, 0.8]);
                addStyle(app.UITable, s3, 'cell', [row3,5*col3]);
            else 
                s2 = uistyle('BackgroundColor', [0.6, 0.8, 1]);
                addStyle(app.UITable, s2, 'cell', [row2,5*col2]);
                
                [row3, col3] = find((app.UITable.Data.Complete > 0) & (app.UITable.Data.VoteOfAgreement == 0));
                
                if isempty(row3)
                    return;
                else
                    s3 = uistyle('BackgroundColor', [1, 0.6, 0.8]);
                    addStyle(app.UITable, s3, 'cell', [row3,5*col3]);
                end
            end
        end
        
        function updateTable(app, ~)
            data = app.UITable.Data;
            
            %app data to compare to - the case chosen by user
            user_tissue = app.TissueDropDown.Value;
            user_biomarker = app.BiomarkerDropDown.Value;
            temp_user_pc = app.PCModeDropDown.Value;
            
            user_pc = regexprep(temp_user_pc, 'PC ', '');
            user_pc = str2double(user_pc);  
            
            user_vote_like = app.LikeButton.Value;
            user_interpretation_upwards = app.WhatisyourinterpretationTextArea.Value;
            user_interpretation_downwards = app.WhatisyourinterpretationTextArea_2.Value;
            
            % row of interest:
            current_row = data(strcmp(data.Tissue, user_tissue) & strcmp(data.Biomarker, user_biomarker) & (data.PC== user_pc),:);

            % update vote value and interpretation if one is given
            current_row.Complete = true;
            current_row.VoteOfAgreement = user_vote_like;
            
            %%%%%%%%%%%%%%% Upward User Input %%%%%%%%%%%%%%%%%%%
           
            cell_count_upwards = length(user_interpretation_upwards);
            temp_user_interpretation_upwards = '';
            if  cell_count_upwards > 1
                for i = 1:cell_count_upwards
                    temp_user_interpretation_upwards = [temp_user_interpretation_upwards, user_interpretation_upwards{i}, ' '];
                end 
                user_interpretation_upwards = temp_user_interpretation_upwards;
                
                current_row.UserInterpretationUpwards = {user_interpretation_upwards};
                
            else 
                 current_row.UserInterpretationUpwards = user_interpretation_upwards;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %%%%%%%%%%%%%%% Downward User Input %%%%%%%%%%%%%%%%%%%
           
            cell_count_downwards = length(user_interpretation_downwards);
            temp_user_interpretation_downwards = '';
            if  cell_count_downwards > 1
                for i = 1:cell_count_downwards
                    temp_user_interpretation_downwards = [temp_user_interpretation_downwards, user_interpretation_downwards{i}, ' '];
                end 
                user_interpretation_downwards = temp_user_interpretation_downwards;
                
                current_row.UserInterpretationDownwards = {user_interpretation_downwards};
                
            else 
                 current_row.UserInterpretationDownwards = user_interpretation_downwards;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % update table row for viewing in app
            data(strcmp(data.Tissue, user_tissue) & strcmp(data.Biomarker, user_biomarker) & (data.PC== user_pc),:) = current_row;
            sorted_data = sortrows(data,1,"descend");                       
            app.UITable.Data = sorted_data;
            
            % update color of case 'Complete' column cell
            [row,col] = find(app.UITable.Data.Complete > 0);
            s = uistyle('BackgroundColor', [0,0.7,0.2]);
            addStyle(app.UITable, s, 'cell', [row,col]);
            
            % updating color of user vote to delineate 'like' or 'dislike'
            [row2, col2] = find((app.UITable.Data.Complete > 0) & (app.UITable.Data.VoteOfAgreement > 0));
            pause(0.001);
            
            if isempty(row2)
                [row3, col3] = find((app.UITable.Data.Complete > 0) & (app.UITable.Data.VoteOfAgreement == 0));
                s3 = uistyle('BackgroundColor', [1, 0.6, 0.8]);
                addStyle(app.UITable, s3, 'cell', [row3,5*col3]);
            else 
                s2 = uistyle('BackgroundColor', [0.6, 0.8, 1]);
                addStyle(app.UITable, s2, 'cell', [row2,5*col2]);                
                [row3, col3] = find((app.UITable.Data.Complete > 0) & (app.UITable.Data.VoteOfAgreement == 0));
                
                if isempty(row3)
                    return;
                else
                    s3 = uistyle('BackgroundColor', [1, 0.6, 0.8]);
                    addStyle(app.UITable, s3, 'cell', [row3,5*col3]);
                end
            end                         
        end
        
        function initializeCommunityInterpretation(app)
            if isequal(app.TissueDropDown.Value, 'SELECT') || isequal(app.BiomarkerDropDown.Value, 'SELECT') || isequal(app.PCModeDropDown.Value, 'SELECT')
                return;
            else                        
                data = app.UITable.Data;
                
                %app data to compare to - the case chosen by user
                user_tissue = app.TissueDropDown.Value;
                user_biomarker = app.BiomarkerDropDown.Value;
                temp_user_pc = app.PCModeDropDown.Value;
                user_pc = regexprep(temp_user_pc, 'PC ', '');
                user_pc = str2double(user_pc);
                
                current_row = data(strcmp(data.Tissue, user_tissue) & strcmp(data.Biomarker, user_biomarker) & (data.PC== user_pc),:);                

                app.CommunityUpwardInterpretationTextArea.Value = current_row.CommunityInterpretationUpwards{1};
                app.CommunityDownwardInterpretationTextArea.Value = current_row.CommunityInterpretationDownwards{1};
                
            end
        end
          
        function updateUserInterpretation(app)
            % this function allows the user interpretation (if one is given)
            % to be viewable on the app view when 'dislike' is chosen;
            % default value is currently (')
            % the value will change as different tissue/biomarker/pc
            % values are chosen, and will update as user input is submitted
            % at 'save'
            if isequal(app.TissueDropDown.Value, 'SELECT') || isequal(app.BiomarkerDropDown.Value, 'SELECT') || isequal(app.PCModeDropDown.Value, 'SELECT')
                return;
            else
                data = app.UITable.Data;
                
                %app data to compare to - the case chosen by user
                user_tissue = app.TissueDropDown.Value;
                user_biomarker = app.BiomarkerDropDown.Value;
                temp_user_pc = app.PCModeDropDown.Value; 
                user_pc = regexprep(temp_user_pc, 'PC ', '');
                user_pc = str2double(user_pc);
                
                current_row = data(strcmp(data.Tissue, user_tissue) & strcmp(data.Biomarker, user_biomarker) & (data.PC== user_pc),:);
                
                app.WhatisyourinterpretationTextArea.Value = current_row.UserInterpretationUpwards{1};
                app.WhatisyourinterpretationTextArea_2.Value = current_row.UserInterpretationDownwards{1};

            end 
        end
         
        function voteViewableState(app)
            
            if isequal(app.TissueDropDown.Value, 'SELECT') || isequal(app.BiomarkerDropDown.Value, 'SELECT') || isequal(app.PCModeDropDown.Value, 'SELECT')
                return;
            else
                data = app.UITable.Data;
                
                user_tissue = app.TissueDropDown.Value;
                user_biomarker = app.BiomarkerDropDown.Value;
                temp_user_pc = app.PCModeDropDown.Value;
                user_pc = regexprep(temp_user_pc, 'PC ', '');
                user_pc = str2double(user_pc);
                
                current_row = data(strcmp(data.Tissue, user_tissue) & strcmp(data.Biomarker, user_biomarker) & (data.PC== user_pc),:);
                current_vote = current_row.VoteOfAgreement;
                
                if (current_vote == true)
                    app.LikeButton.Value = true;
                    
                    % these statements reset like/dislike button and
                    % dislike comment when going from a case where the user
                    % voted dislike (the editable fields appear), and then
                    % going to a new case (where default dislike comment should not appear)
                    
                    app.WhatisyourinterpretationTextArea.Visible = "off";
                    app.WhatisyourinterpretationTextAreaLabel.Visible = "off";
                    
                    app.WhatisyourinterpretationTextArea_2.Visible = "off";
                    app.WhatisyourinterpretationTextAreaLabel_2.Visible = "off";
                else 
                    app.LikeButton.Value = false;
                    app.WhatisyourinterpretationTextArea.Visible = "on";
                    app.WhatisyourinterpretationTextAreaLabel.Visible = "on";
                    
                    app.WhatisyourinterpretationTextArea_2.Visible = "on";
                    app.WhatisyourinterpretationTextAreaLabel_2.Visible = "on";
                end 
            end 
            
        end
        
        
        function disableSidePanel(app)
            app.LeftPanel.Enable = "off";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.WhatisyourinterpretationTextArea.Visible = "off";
            app.WhatisyourinterpretationTextAreaLabel.Visible = "off";
            
            app.WhatisyourinterpretationTextArea_2.Visible = "off";
            app.WhatisyourinterpretationTextAreaLabel_2.Visible = "off";
            app.ImageAxes.Visible = 'off';
            
            app.InitializeTable();
            
            files = matlab.apputil.getInstalledAppInfo;
            
            if isempty(files)
                curr_path = pwd;
                style_curr_path = fullfile(curr_path, 'style');
            else 
                [curr_path,~,~] = fileparts(files(1).location);
%                 style_curr_path = fullfile(curr_path, 'interpreter_comm_engagement_dual_pc_autoreflow', 'style');
                style_curr_path = fullfile(curr_path, 'pc_interpreter/Documents', 'style');

            end
            
            addpath(style_curr_path);
            
            % 09/15
            logoFile = 'logo.png';
            imageAbsolute = fullfile(style_curr_path, logoFile);
            app.Image.ImageSource = (imageAbsolute);
%             uiimage(app.UIFigure,"ImageSource",imageAbsolute);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {164, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: TissueDropDown
        function TissueDropDownValueChanged(app, event)
            deleteFigs(app);
            updateFigs(app);
            initializeCommunityInterpretation(app);
            updateUserInterpretation(app);            
            voteViewableState(app);
        end

        % Value changed function: BiomarkerDropDown, PCModeDropDown
        function BiomarkerDropDownValueChanged(app, event)
            deleteFigs(app);
            updateFigs(app);
            initializeCommunityInterpretation(app);
            updateUserInterpretation(app);            
            voteViewableState(app);
        end

        % Callback function
        function PCModeDropDownValueChanged(app, event)
            deleteFigs(app);
            updateFigs(app);
            initializeCommunityInterpretation(app);
            updateUserInterpretation(app);
            voteViewableState(app);
        end

        % Value changed function: SaveButton
        function SaveButtonValueChanged(app, event)
            
            % User attempts to save with missing selection values:
            if isempty(app.dir_name)
                report = "Please select your desired output directory";
                uialert(app.UIFigure, report, "Output Directory", "Icon","warning");
                return;
            elseif isequal(app.TissueDropDown.Value, 'SELECT')
                report = "Please select your desired tissue value";
                uialert(app.UIFigure, report, "Tissue Selection", "Icon","warning"); 
                
                return;
            elseif isequal(app.BiomarkerDropDown.Value, 'SELECT')
                report = "Please select your desired output directory";
                uialert(app.UIFigure, report, "Output Directory", "Icon","warning");
                
                return;
            elseif isequal(app.PCModeDropDown.Value, 'SELECT')
                report = "Please select your desired PC mode value";
                uialert(app.UIFigure, report, "PC mode Selection", "Icon","warning");
                
                return;
            elseif isequal(app.TissueDropDown.Value, 'SELECT') && isequal(app.PCModeDropDown.Value, 'SELECT')
                report = "Multiple missing selection values";
                uialert(app.UIFigure, report, "Missing Selections", "Icon","warning");
                
                return;
            elseif isequal(app.TissueDropDown.Value, 'SELECT') && isequal(app.BiomarkerDropDown.Value, 'SELECT')
                report = "Multiple missing selection values";
                uialert(app.UIFigure, report, "Missing Selections", "Icon","warning");
                
                return;
            elseif isequal(app.BiomarkerDropDown.Value, 'SELECT') && isequal(app.PCModeDropDown.Value, 'SELECT')
                report = "Multiple missing selection values";
                uialert(app.UIFigure, report, "Missing Selections", "Icon","warning");                
                return;
            end
            
            temp_tissue = app.TissueDropDown.Value;
            if isequal(temp_tissue, 'Femur') || isequal(temp_tissue, 'Patella') || isequal(temp_tissue, 'Tibia')
                tissue = temp_tissue;
            else
                tissue = regexprep(temp_tissue, ' ', '_');
            end
                
            temp_bio = app.BiomarkerDropDown.Value;
            if isequal(temp_bio, 'Bone Shape')
                bio = regexprep(temp_bio, ' ', '_');
            else 
                bio = regexprep(temp_bio, 'Cartilage', '');
                bio = regexprep(bio, ' ', '');
            end

            pc = app.PCModeDropDown.Value;
            pc = regexprep(pc, ' ', '');

            filename = [tissue,'_',bio,'_',pc,'.jpg'];
            datafile = [tissue,'_',bio,'_',pc,'.mat'];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%% Entering new folder to save image %%%%%%
            %save image of app after user vote determined
            main_curr_path = pwd;            
            cd(app.dir_name);
            exportapp(app.UIFigure,filename)
            
            interp = app.WhatisyourinterpretationTextArea.Value;
            vote = app.LikeButton.Value;
            %save data values to eventually put in table or something
            save(datafile, 'tissue', 'bio', 'pc', 'vote', 'interp');
            
            cd(main_curr_path);
            %%%% Save complete - now should return to original folder %%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Update table with case reviewed by user:
            app.updateTable();
            
            % Once the data has been changed and saved to sheet
            % accordingly, commence resetting of text value when user moves
            % to other cases                                   

            report = "Save Successful";
            uialert(app.UIFigure, report, "Success", "Icon","success");

        end

        % Button down function: InterpretationVoteButtonGroup
        function InterpretationVoteButtonGroupButtonDown(app, event)
            
            if (app.LikeButton.Value == false)
                app.WhatisyourinterpretationTextArea.Visible = "on";
                app.WhatisyourinterpretationTextAreaLabel.Visible = "on";
                app.WhatisyourinterpretationTextArea_2.Visible = "on";
                app.WhatisyourinterpretationTextAreaLabel_2.Visible = "on";
            else
                app.WhatisyourinterpretationTextArea.Visible = "off";
                app.WhatisyourinterpretationTextAreaLabel.Visible = "off";
                app.WhatisyourinterpretationTextArea_2.Visible = "off";
                app.WhatisyourinterpretationTextAreaLabel_2.Visible = "off";
                app.WhatisyourinterpretationTextAreaValueChanged();
            end
        end

        % Selection changed function: InterpretationVoteButtonGroup
        function InterpretationVoteButtonGroupSelectionChanged(app, event)

            if (app.LikeButton.Value == false)
                app.WhatisyourinterpretationTextArea.Visible = "on";
                app.WhatisyourinterpretationTextAreaLabel.Visible = "on";
                app.WhatisyourinterpretationTextArea_2.Visible = "on";
                app.WhatisyourinterpretationTextAreaLabel_2.Visible = "on";
                
            else
                app.WhatisyourinterpretationTextArea.Visible = "off";
                app.WhatisyourinterpretationTextAreaLabel.Visible = "off";
                app.WhatisyourinterpretationTextArea_2.Visible = "off";
                app.WhatisyourinterpretationTextAreaLabel_2.Visible = "off";
            end
        end

        % Button pushed function: OutputDirectoryButton
        function OutputDirectoryButtonPushed(app, event)
            app.dir_name = uigetdir();
            report = "Output Directory Chosen Successfully";
            uialert(app.UIFigure, report, "Success", "Icon","success");
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            if sum(app.UITable.Data.Complete) < 110
%                 filename = fullfile(app.dir_name, 'user_data_partially_complete.xlsx'); %excel 
                filename = fullfile(app.dir_name, 'user_data_partially_complete.csv');

                writetable(app.UITable.Data, filename)
                app.SeeYouSoonLabel.Visible = 'on';
                report = "Data Exported Successfully";
                uialert(app.UIFigure, report, "Success", "Icon","success");
            else 
%                 filename = fullfile(app.dir_name, 'user_data_complete.xlsx'); %excel 
                filename = fullfile(app.dir_name, 'user_data_complete.csv');

                writetable(app.UITable.Data, filename)
                app.YoureDoneLabel.Visible = 'on';
                report = "Data Exported Successfully";
                uialert(app.UIFigure, report, "Success", "Icon","success");
            end 
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Load the data
            [baseName, folder] = uigetfile;
            addpath(folder);
            app.user_table = readtable(baseName);
            app.InitializeTable();
            
            report = "Data Imported Successfully";
            uialert(app.UIFigure, report, "Success", "Icon","success");
            
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            
            if isequal(app.TissueDropDown.Value, 'SELECT') || isequal(app.BiomarkerDropDown.Value, 'SELECT') || isequal(app.PCModeDropDown.Value, 'SELECT')
                return;
            else
                %connect three subplot axes so they rotate in unison
                children = app.Panel.Children;
                
                if isempty(children)
                    return
                else 
                    app.hlink2 = linkprop([children(2),children(3),children(1)],{'CameraPosition','CameraUpVector'}); 
                    rotate3d on;
                end
            end 
        end

        % Button down function: SetupExportTab
        function SetupExportTabButtonDown(app, event)
            app.disableSidePanel()
        end

        % Button down function: CheckListTab
        function CheckListTabButtonDown(app, event)
            app.disableSidePanel()         
        end

        % Button down function: InterpretationsTab
        function InterpretationsTabButtonDown(app, event)
            app.LeftPanel.Enable = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SaveButton = uibutton(app.LeftPanel, 'state');
            app.SaveButton.ValueChangedFcn = createCallbackFcn(app, @SaveButtonValueChanged, true);
            app.SaveButton.Text = 'Save';
            app.InterpretationVoteButtonGroup = uibuttongroup(app.LeftPanel);
            app.InterpretationVoteButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InterpretationVoteButtonGroupSelectionChanged, true);
            app.InterpretationVoteButtonGroup.Title = 'Interpretation Vote';
            app.InterpretationVoteButtonGroup.ButtonDownFcn = createCallbackFcn(app, @InterpretationVoteButtonGroupButtonDown, true);
            app.LikeButton = uiradiobutton(app.InterpretationVoteButtonGroup);
            app.LikeButton.Text = 'Like';
            app.LikeButton.Value = true;
            app.DislikeButton = uiradiobutton(app.InterpretationVoteButtonGroup);
            app.DislikeButton.Text = 'Dislike';
            app.PCModeDropDownLabel = uilabel(app.LeftPanel);
            app.PCModeDropDownLabel.Text = 'PC Mode';
            app.BiomarkerDropDown_2Label = uilabel(app.LeftPanel);
            app.BiomarkerDropDown_2Label.Text = 'Biomarker';
            app.PCModeDropDown = uidropdown(app.LeftPanel);
            app.PCModeDropDown.Items = {'SELECT', 'PC 1', 'PC 2', 'PC 3', 'PC 4', 'PC 5', 'PC 6', 'PC 7', 'PC 8', 'PC 9', 'PC 10'};
            app.PCModeDropDown.ValueChangedFcn = createCallbackFcn(app, @BiomarkerDropDownValueChanged, true);
            app.PCModeDropDown.Placeholder = 'SELECT';
            app.PCModeDropDown.Value = 'SELECT';
            app.BiomarkerDropDown = uidropdown(app.LeftPanel);
            app.BiomarkerDropDown.Items = {'SELECT', 'Bone Shape', 'Cartilage Thickness', 'Cartilage T2'};
            app.BiomarkerDropDown.ValueChangedFcn = createCallbackFcn(app, @BiomarkerDropDownValueChanged, true);
            app.BiomarkerDropDown.Placeholder = 'SELECT';
            app.BiomarkerDropDown.Value = 'SELECT';
            app.TissueDropDownLabel = uilabel(app.LeftPanel);
            app.TissueDropDownLabel.Text = 'Tissue';
            app.TissueDropDown = uidropdown(app.LeftPanel);
            app.TissueDropDown.Items = {'SELECT', 'Femur', 'Patella', 'Lateral Meniscus', 'Medial Meniscus', 'Tibia'};
            app.TissueDropDown.ValueChangedFcn = createCallbackFcn(app, @TissueDropDownValueChanged, true);
            app.TissueDropDown.Placeholder = 'SELECT';
            app.TissueDropDown.Value = 'SELECT';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.InterpretationsTab = uitab(app.TabGroup);
            app.InterpretationsTab.Title = 'Interpretations';
            app.InterpretationsTab.ButtonDownFcn = createCallbackFcn(app, @InterpretationsTabButtonDown, true);
            app.GridLayout3 = uigridlayout(app.InterpretationsTab);
            app.ImageAxes = uiaxes(app.GridLayout3);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.Panel = uipanel(app.GridLayout3);
            app.WhatisyourinterpretationTextArea = uitextarea(app.GridLayout3);
            app.WhatisyourinterpretationTextAreaLabel = uilabel(app.GridLayout3);
            app.WhatisyourinterpretationTextAreaLabel.Text = 'Your Upward Mode Interpretation';
            app.WhatisyourinterpretationTextAreaLabel_2 = uilabel(app.GridLayout3);
            app.WhatisyourinterpretationTextAreaLabel_2.Text = 'Your Downward Mode Interpretation';
            app.WhatisyourinterpretationTextArea_2 = uitextarea(app.GridLayout3);
            app.CommunityUpwardInterpretationTextArea = uitextarea(app.GridLayout3);
            app.CommunityUpwardInterpretationTextAreaLabel = uilabel(app.GridLayout3);
            app.CommunityUpwardInterpretationTextAreaLabel.Text = 'Community Upward Interpretation';
            app.CommunityDownwardInterpretationTextArea = uitextarea(app.GridLayout3);
            app.CommunityDownwardInterpretationTextAreaLabel = uilabel(app.GridLayout3);
            app.CommunityDownwardInterpretationTextAreaLabel.Text = 'Community Downward Interpretation';
            app.CheckListTab = uitab(app.TabGroup);
            app.CheckListTab.Title = 'Check List';
            app.CheckListTab.ButtonDownFcn = createCallbackFcn(app, @CheckListTabButtonDown, true);
            app.UITable = uitable(app.CheckListTab);
            app.UITable.ColumnName = {'Complete'; 'Tissue'; 'Biomarker'; 'PC'; 'Vote of Agreement'; 'User Interpretation Upwards'; 'User Interpretation Downwards'; 'Community Interpretation Upwards'; 'Community Interpretation Downwards'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true true true true true true true];
            app.SetupExportTab = uitab(app.TabGroup);
            app.SetupExportTab.Title = 'Setup/Export';
            app.SetupExportTab.ButtonDownFcn = createCallbackFcn(app, @SetupExportTabButtonDown, true);
            app.GridLayout2 = uigridlayout(app.SetupExportTab);
            app.LoadinpartiallycompletedxlsxfiletocontinueLabel = uilabel(app.GridLayout2);
            app.LoadinpartiallycompletedxlsxfiletocontinueLabel.Text = 'Load in partially completed .xlsx file to continue';
            app.LoadDataButton = uibutton(app.GridLayout2, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.OncefinishedexportdatatooutputdirectoryLabel = uilabel(app.GridLayout2);
            app.OncefinishedexportdatatooutputdirectoryLabel.Text = 'Once finished, export data to output directory';
            app.ChoosedirectorytosavedataandprogressLabel = uilabel(app.GridLayout2);
            app.ChoosedirectorytosavedataandprogressLabel.Text = 'Choose directory to save data and progress';
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = 'logo.png';
            app.OutputDirectoryButton = uibutton(app.GridLayout2, 'push');
            app.OutputDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @OutputDirectoryButtonPushed, true);
            app.OutputDirectoryButton.Text = 'Output Directory';
            app.ExportDataButton = uibutton(app.GridLayout2, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.SeeYouSoonLabel = uilabel(app.GridLayout2);
            app.SeeYouSoonLabel.Visible = 'off';
            app.SeeYouSoonLabel.Text = 'See You Soon!';
            app.YoureDoneLabel = uilabel(app.GridLayout2);
            app.YoureDoneLabel.Visible = 'off';
            app.YoureDoneLabel.Text = 'You''re Done!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interpreter_comm_engagement_dual_pc_autoreflow_backup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end